classdef initialize_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ApplyButton                  matlab.ui.control.Button
        ImageDisplayPanel            matlab.ui.container.Panel
        InputImage                   matlab.ui.control.UIAxes
        OutputImage                  matlab.ui.control.UIAxes
        OperationTabGroup            matlab.ui.container.TabGroup
        ConvolutionTab               matlab.ui.container.Tab
        Label4                       matlab.ui.control.Label
        Label3                       matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        Label1                       matlab.ui.control.Label
        MaskList                     matlab.ui.control.Image
        MaskDropDown                 matlab.ui.control.DropDown
        MaskDropDownLabel            matlab.ui.control.Label
        SmoothingBlurringTab         matlab.ui.container.Tab
        FilterTabGroup               matlab.ui.container.TabGroup
        MeanFilterTab                matlab.ui.container.Tab
        MF_mask_size                 matlab.ui.control.NumericEditField
        MaskSzeLabel                 matlab.ui.control.Label
        GaussianFilterTab            matlab.ui.container.Tab
        sigma                        matlab.ui.control.NumericEditField
        MaskSzeLabel_4               matlab.ui.control.Label
        GF_mask_size                 matlab.ui.control.NumericEditField
        MaskSzeLabel_3               matlab.ui.control.Label
        ILPFTab                      matlab.ui.container.Tab
        ILPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_5               matlab.ui.control.Label
        BLPFTab                      matlab.ui.container.Tab
        BLPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_7               matlab.ui.control.Label
        BLPF_mask_size               matlab.ui.control.NumericEditField
        MaskSzeLabel_6               matlab.ui.control.Label
        GLPFTab                      matlab.ui.container.Tab
        GLPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_8               matlab.ui.control.Label
        HPFTab                       matlab.ui.container.Tab
        HPFTabGroup                  matlab.ui.container.TabGroup
        IHPFTab                      matlab.ui.container.Tab
        IHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_20              matlab.ui.control.Label
        BHPFTab                      matlab.ui.container.Tab
        BHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_22              matlab.ui.control.Label
        BHPF_mask_size               matlab.ui.control.NumericEditField
        MaskSzeLabel_21              matlab.ui.control.Label
        GHPFTab                      matlab.ui.container.Tab
        GHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_23              matlab.ui.control.Label
        No4Tab                       matlab.ui.container.Tab
        metode                       matlab.ui.control.Label
        No4_ImageDropDown            matlab.ui.control.DropDown
        ImageDropDownLabel           matlab.ui.control.Label
        RestorationTab               matlab.ui.container.Tab
        NoiseDropDown                matlab.ui.control.DropDown
        NoiseDensity                 matlab.ui.control.NumericEditField
        MaskSzeLabel_24              matlab.ui.control.Label
        Noise_mask_size_m            matlab.ui.control.NumericEditField
        Noise_mask_size_n            matlab.ui.control.NumericEditField
        MaskSzeLabel_32              matlab.ui.control.Label
        MaskSzeLabel_31              matlab.ui.control.Label
        NoiseDropDownLabel           matlab.ui.control.Label
        RestorationTabGroup          matlab.ui.container.TabGroup
        MinFilterTab                 matlab.ui.container.Tab
        MaxFilterTab                 matlab.ui.container.Tab
        MedianFilterTab              matlab.ui.container.Tab
        ArithmeticMeanFilterTab      matlab.ui.container.Tab
        GeometricMeanFilterTab       matlab.ui.container.Tab
        HarmonicMeanFilterTab        matlab.ui.container.Tab
        ContraharmonicMeanFilterTab  matlab.ui.container.Tab
        CMF_Q                        matlab.ui.control.NumericEditField
        MaskSzeLabel_65              matlab.ui.control.Label
        MidpointFilterTab            matlab.ui.container.Tab
        AlphatrimmedMeanFilterTab    matlab.ui.container.Tab
        ATMF_d                       matlab.ui.control.NumericEditField
        MaskSzeLabel_64              matlab.ui.control.Label
        RestorationDistortedImage    matlab.ui.control.UIAxes
        NotchFilterTab               matlab.ui.container.Tab
        ATMF_density_2               matlab.ui.control.NumericEditField
        MaskSzeLabel_66              matlab.ui.control.Label
        Notch_ImageDropDown          matlab.ui.control.DropDown
        ImageDropDown_2Label         matlab.ui.control.Label
        MotionDeblurringTab          matlab.ui.container.Tab
        NSRSlider                    matlab.ui.control.Slider
        DeblurringApplyBlur          matlab.ui.control.CheckBox
        DeblurringTabGroup           matlab.ui.container.TabGroup
        DeblurringMotionBlurTab      matlab.ui.container.Tab
        MaskSzeLabel_69              matlab.ui.control.Label
        MotionBlur_distance          matlab.ui.control.NumericEditField
        MotionBlur_angle             matlab.ui.control.NumericEditField
        MaskSzeLabel_68              matlab.ui.control.Label
        DeblurringGaussianTab        matlab.ui.container.Tab
        MaskSzeLabel_71              matlab.ui.control.Label
        GaussianBlur_size            matlab.ui.control.NumericEditField
        GaussianBlur_sigma           matlab.ui.control.NumericEditField
        MaskSzeLabel_70              matlab.ui.control.Label
        NSRSliderLabel               matlab.ui.control.Label
        DeblurringDistortedImage     matlab.ui.control.UIAxes
        SelectImageButton            matlab.ui.control.Button
        SelectedImageLabel           matlab.ui.control.Label
        ChooseImageLabel             matlab.ui.control.Label
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        pathToFile (1,1) string % Path to the currently loaded image file.
    end
    
    properties (Access = private)
        pathToNo4Image1 (1,1) string {mustBeFile(pathToNo4Image1)} = 'test/4/17.jpg';
        pathToNo4Image2 (1,1) string {mustBeFile(pathToNo4Image2)} = 'test/4/19.jpg';
    end
    
    
    methods (Access = private)
        
        function [Iout] = readImage(~, path)
            [Iout, palette] = imread(path);

            if ~isempty(palette)
                [M, N, ~] = size(Iout);
                C = size(palette, 2);
                IoutNonindexed = zeros(M, N, C);
                
                for i = 1 : M
                    for j = 1 : N
                        IoutNonindexed(i, j, :) = uint8(palette(Iout(i, j, 1) + 1, :) * 255);
                    end
                end

                Iout = IoutNonindexed;
                Iout = uint8(Iout);
            end
        end

        function [] = clearDisplays(comp)
            cla(comp.InputImage);
            cla(comp.OutputImage);
        end

        function [] = disableInteractable(comp)
            comp.SelectImageButton.Enable = 'off';
            comp.ApplyButton.Enable = 'off';
        end

        function [] = enableInteractable(comp)
            comp.SelectImageButton.Enable = 'on';
            comp.ApplyButton.Enable = comp.pathToFile ~= '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({ ...
                '*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'All files'; ...
                '*.jpg;*.jpeg', 'JPG files'; ...
                '*.png', 'PNG files'; ...
                '*.bmp', 'BMP files'; ...
                '*.tif;*.tiff', 'TIFF files' ...
            });
            if fileLoc ~= 0
                clearDisplays(comp);

                comp.pathToFile = join([fileLoc, fileName], '\');
                comp.SelectedImageLabel.Text = comp.pathToFile;
                I = readImage(comp, comp.pathToFile);
                imshow(I, 'Parent', comp.InputImage);

                comp.ApplyButton.Enable = comp.pathToFile ~= '';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            I = readImage(comp, comp.pathToFile);
            disableInteractable(comp);

            switch comp.OperationTabGroup.SelectedTab
                case comp.ConvolutionTab
                    switch comp.MaskDropDown.Value
                        case 'Mask 1'
                            G = [1 2 1; 2 4 2; 1 2 1] / 16;
                        case 'Mask 2'
                            G = [0 -1 0; -1 4 -1; 0 -1 0];
                        case 'Mask 3'
                            G = [1 1 2 2 2 1 1; 1 2 2 4 2 2 1; 2 2 4 8 4 2 2; 2 4 8 16 8 4 2; 2 2 4 8 4 2 2; 1 2 2 4 2 2 1; 1 1 2 2 2 1 1] / 140;
                        case 'Mask 4'
                            G = [-1 -1 -1; -1 17 -1; -1 -1 -1];
                    end
                    I2 = convolution(I, G);
                case comp.SmoothingBlurringTab
                    switch comp.FilterTabGroup.SelectedTab
                        case comp.MeanFilterTab
                            I2 = mean_filter(I, comp.MF_mask_size.Value);
                        case comp.GaussianFilterTab
                            I2 = gaussian_filter(I, comp.GF_mask_size.Value, comp.sigma.Value);
                        case comp.ILPFTab
                            [~, ~, ~, I2] = ILPF(I, comp.ILPF_D0.Value);
                        case comp.BLPFTab
                            [~, ~, ~, I2] = BLPF(I, comp.BLPF_D0.Value, comp.BLPF_mask_size.Value);
                        case comp.GLPFTab
                            [~, ~, ~, I2] = GLPF(I, comp.GLPF_D0.Value);
                    end
                case comp.HPFTab
                    switch comp.HPFTabGroup.SelectedTab
                        case comp.IHPFTab
                            [~, ~, ~, I2] = IHPF(I, comp.IHPF_D0.Value);
                        case comp.BHPFTab
                            [~, ~, ~, I2] = BHPF(I, comp.BHPF_D0.Value, comp.BHPF_mask_size.Value);
                        case comp.GHPFTab
                            [~, ~, ~, I2] = GHPF(I, comp.GHPF_D0.Value);
                    end
                case comp.No4Tab
                case comp.RestorationTab
                    switch comp.NoiseDropDown.Value
                        case 'Salt & Pepper'
                            n_type = 'salt&pepper';
                        case 'Gaussian'
                            n_type = 'gaussian';
                    end
                    n_density = comp.NoiseDensity.Value;
                    n_m = comp.Noise_mask_size_m.Value;
                    n_n = comp.Noise_mask_size_n.Value;

                    switch comp.RestorationTabGroup.SelectedTab
                        case comp.MinFilterTab
                            [Id, I2] = min_filter(I, n_type, n_density, n_m, n_n);
                        case comp.MaxFilterTab
                            [Id, I2] = max_filter(I, n_type, n_density, n_m, n_n);
                        case comp.MedianFilterTab
                            [Id, I2] = median_filter(I, n_type, n_density, n_m, n_n);
                        case comp.ArithmeticMeanFilterTab
                            [Id, I2] = median_filter(I, n_type, n_density, n_m, n_n);
                        case comp.GeometricMeanFilterTab
                            [Id, I2] = geometric_mean_filter(I, n_type, n_density, n_m, n_n);
                        case comp.HarmonicMeanFilterTab
                            [Id, I2] = harmonic_mean_filter(I, n_type, n_density, n_m, n_n);
                        case comp.ContraharmonicMeanFilterTab
                            [Id, I2] = contraharmonic_mean_filter(I, n_type, n_density, n_m, n_n, comp.CMF_Q.Value);
                        case comp.MidpointFilterTab
                            [Id, I2] = midpoint_filter(I, n_type, n_density, n_m, n_n);
                        case comp.AlphatrimmedMeanFilterTab
                            [Id, I2] = alphatrimmed_mean_filter(I, n_type, n_density, n_m, n_n, comp.ATMF_d.Value);
                    end

                    imshow(Id, 'Parent', comp.RestorationDistortedImage);
                case comp.NotchFilterTab
                case comp.MotionDeblurringTab
                    switch comp.DeblurringTabGroup.SelectedTab
                        case comp.DeblurringMotionBlurTab
                            PSF = fspecial('motion', comp.MotionBlur_distance.Value, comp.MotionBlur_angle.Value);
                        case comp.DeblurringGaussianTab
                            PSF = fspecial('gaussian', comp.GaussianBlur_size.Value, comp.GaussianBlur_sigma.Value);
                    end

                    I1 = I(:, :, :);
                    if comp.DeblurringApplyBlur.Value
                        I1 = imfilter(I1, PSF, 'conv', 'circular');
                    end

                    imshow(I1, 'Parent', comp.DeblurringDistortedImage);
                    [~, I2] = wiener_deconv(I1, PSF, comp.NSRSlider.Value);
            end

            imshow(I2, 'Parent', comp.OutputImage);
            enableInteractable(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 909 601];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ChooseImageLabel = uilabel(comp);
            comp.ChooseImageLabel.Text = 'Choose image';
            comp.SelectedImageLabel = uilabel(comp);
            comp.SelectedImageLabel.Enable = 'off';
            comp.SelectedImageLabel.Text = 'No image selected';
            comp.SelectImageButton = uibutton(comp, 'push');
            comp.SelectImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectImageButtonPushed, true);
            comp.SelectImageButton.Text = 'Select';
            comp.OperationTabGroup = uitabgroup(comp);
            comp.ConvolutionTab = uitab(comp.OperationTabGroup);
            comp.ConvolutionTab.Title = 'Convolution';
            comp.MaskDropDownLabel = uilabel(comp.ConvolutionTab);
            comp.MaskDropDownLabel.Text = 'Mask';
            comp.MaskDropDown = uidropdown(comp.ConvolutionTab);
            comp.MaskDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            comp.MaskDropDown.Value = 'Mask 1';
            comp.MaskList = uiimage(comp.ConvolutionTab);
            comp.MaskList.ImageSource = fullfile(pathToMLAPP, 'convolution_mask.png');
            comp.Label1 = uilabel(comp.ConvolutionTab);
            comp.Label1.Text = '1';
            comp.Label2 = uilabel(comp.ConvolutionTab);
            comp.Label2.Text = '2';
            comp.Label3 = uilabel(comp.ConvolutionTab);
            comp.Label3.Text = '3';
            comp.Label4 = uilabel(comp.ConvolutionTab);
            comp.Label4.Text = '4';
            comp.SmoothingBlurringTab = uitab(comp.OperationTabGroup);
            comp.SmoothingBlurringTab.Title = 'Smoothing/Blurring';
            comp.FilterTabGroup = uitabgroup(comp.SmoothingBlurringTab);
            comp.MeanFilterTab = uitab(comp.FilterTabGroup);
            comp.MeanFilterTab.Title = 'Mean Filter';
            comp.MaskSzeLabel = uilabel(comp.MeanFilterTab);
            comp.MaskSzeLabel.Text = 'Mask Size';
            comp.MF_mask_size = uieditfield(comp.MeanFilterTab, 'numeric');
            comp.MF_mask_size.Limits = [1 Inf];
            comp.MF_mask_size.Value = 5;
            comp.GaussianFilterTab = uitab(comp.FilterTabGroup);
            comp.GaussianFilterTab.Title = 'Gaussian Filter';
            comp.MaskSzeLabel_3 = uilabel(comp.GaussianFilterTab);
            comp.MaskSzeLabel_3.Text = 'Mask Size';
            comp.GF_mask_size = uieditfield(comp.GaussianFilterTab, 'numeric');
            comp.GF_mask_size.Limits = [0 Inf];
            comp.GF_mask_size.Value = 5;
            comp.MaskSzeLabel_4 = uilabel(comp.GaussianFilterTab);
            comp.MaskSzeLabel_4.Text = 'sigma';
            comp.sigma = uieditfield(comp.GaussianFilterTab, 'numeric');
            comp.sigma.Limits = [0 Inf];
            comp.sigma.Value = 1;
            comp.ILPFTab = uitab(comp.FilterTabGroup);
            comp.ILPFTab.Title = 'ILPF';
            comp.MaskSzeLabel_5 = uilabel(comp.ILPFTab);
            comp.MaskSzeLabel_5.Text = 'D0';
            comp.ILPF_D0 = uieditfield(comp.ILPFTab, 'numeric');
            comp.ILPF_D0.Limits = [0 Inf];
            comp.ILPF_D0.Value = 64;
            comp.BLPFTab = uitab(comp.FilterTabGroup);
            comp.BLPFTab.Title = 'BLPF';
            comp.MaskSzeLabel_6 = uilabel(comp.BLPFTab);
            comp.MaskSzeLabel_6.Text = 'Mask Size';
            comp.BLPF_mask_size = uieditfield(comp.BLPFTab, 'numeric');
            comp.BLPF_mask_size.Limits = [0 Inf];
            comp.BLPF_mask_size.Value = 3;
            comp.MaskSzeLabel_7 = uilabel(comp.BLPFTab);
            comp.MaskSzeLabel_7.Text = 'D0';
            comp.BLPF_D0 = uieditfield(comp.BLPFTab, 'numeric');
            comp.BLPF_D0.Limits = [0 Inf];
            comp.BLPF_D0.Value = 64;
            comp.GLPFTab = uitab(comp.FilterTabGroup);
            comp.GLPFTab.Title = 'GLPF';
            comp.MaskSzeLabel_8 = uilabel(comp.GLPFTab);
            comp.MaskSzeLabel_8.Text = 'D0';
            comp.GLPF_D0 = uieditfield(comp.GLPFTab, 'numeric');
            comp.GLPF_D0.Limits = [0 Inf];
            comp.GLPF_D0.Value = 64;
            comp.HPFTab = uitab(comp.OperationTabGroup);
            comp.HPFTab.Title = 'HPF';
            comp.HPFTabGroup = uitabgroup(comp.HPFTab);
            comp.IHPFTab = uitab(comp.HPFTabGroup);
            comp.IHPFTab.Title = 'IHPF';
            comp.MaskSzeLabel_20 = uilabel(comp.IHPFTab);
            comp.MaskSzeLabel_20.Text = 'D0';
            comp.IHPF_D0 = uieditfield(comp.IHPFTab, 'numeric');
            comp.IHPF_D0.Limits = [0 Inf];
            comp.IHPF_D0.Value = 64;
            comp.BHPFTab = uitab(comp.HPFTabGroup);
            comp.BHPFTab.Title = 'BHPF';
            comp.MaskSzeLabel_21 = uilabel(comp.BHPFTab);
            comp.MaskSzeLabel_21.Text = 'Mask Size';
            comp.BHPF_mask_size = uieditfield(comp.BHPFTab, 'numeric');
            comp.BHPF_mask_size.Limits = [0 Inf];
            comp.BHPF_mask_size.Value = 3;
            comp.MaskSzeLabel_22 = uilabel(comp.BHPFTab);
            comp.MaskSzeLabel_22.Text = 'D0';
            comp.BHPF_D0 = uieditfield(comp.BHPFTab, 'numeric');
            comp.BHPF_D0.Limits = [0 Inf];
            comp.BHPF_D0.Value = 64;
            comp.GHPFTab = uitab(comp.HPFTabGroup);
            comp.GHPFTab.Title = 'GHPF';
            comp.MaskSzeLabel_23 = uilabel(comp.GHPFTab);
            comp.MaskSzeLabel_23.Text = 'D0';
            comp.GHPF_D0 = uieditfield(comp.GHPFTab, 'numeric');
            comp.GHPF_D0.Limits = [0 Inf];
            comp.GHPF_D0.Value = 64;
            comp.No4Tab = uitab(comp.OperationTabGroup);
            comp.No4Tab.Title = 'No.4';
            comp.ImageDropDownLabel = uilabel(comp.No4Tab);
            comp.ImageDropDownLabel.Text = 'Image';
            comp.No4_ImageDropDown = uidropdown(comp.No4Tab);
            comp.No4_ImageDropDown.Items = {'Image 1', 'Image 2'};
            comp.No4_ImageDropDown.Value = 'Image 1';
            comp.metode = uilabel(comp.No4Tab);
            comp.metode.Text = {'Metode yang digunakan:'; '1. ...'; '2. ...'; '3. ...'};
            comp.RestorationTab = uitab(comp.OperationTabGroup);
            comp.RestorationTab.Title = 'Restoration';
            comp.RestorationDistortedImage = uiaxes(comp.RestorationTab);
            title(comp.RestorationDistortedImage, 'Distorted image')
            comp.RestorationDistortedImage.XTick = [];
            comp.RestorationDistortedImage.YTick = [];
            comp.RestorationTabGroup = uitabgroup(comp.RestorationTab);
            comp.MinFilterTab = uitab(comp.RestorationTabGroup);
            comp.MinFilterTab.Title = 'Min Filter';
            comp.MaxFilterTab = uitab(comp.RestorationTabGroup);
            comp.MaxFilterTab.Title = 'Max Filter';
            comp.MedianFilterTab = uitab(comp.RestorationTabGroup);
            comp.MedianFilterTab.Title = 'Median Filter';
            comp.ArithmeticMeanFilterTab = uitab(comp.RestorationTabGroup);
            comp.ArithmeticMeanFilterTab.Title = 'Arithmetic Mean Filter';
            comp.GeometricMeanFilterTab = uitab(comp.RestorationTabGroup);
            comp.GeometricMeanFilterTab.Title = 'Geometric Mean Filter';
            comp.HarmonicMeanFilterTab = uitab(comp.RestorationTabGroup);
            comp.HarmonicMeanFilterTab.Title = 'Harmonic Mean Filter';
            comp.ContraharmonicMeanFilterTab = uitab(comp.RestorationTabGroup);
            comp.ContraharmonicMeanFilterTab.Title = 'Contraharmonic Mean Filter';
            comp.MaskSzeLabel_65 = uilabel(comp.ContraharmonicMeanFilterTab);
            comp.MaskSzeLabel_65.Text = 'Q';
            comp.CMF_Q = uieditfield(comp.ContraharmonicMeanFilterTab, 'numeric');
            comp.CMF_Q.Limits = [0 Inf];
            comp.CMF_Q.Value = 1;
            comp.MidpointFilterTab = uitab(comp.RestorationTabGroup);
            comp.MidpointFilterTab.Title = 'Midpoint Filter';
            comp.AlphatrimmedMeanFilterTab = uitab(comp.RestorationTabGroup);
            comp.AlphatrimmedMeanFilterTab.Title = 'Alpha-trimmed Mean Filter';
            comp.MaskSzeLabel_64 = uilabel(comp.AlphatrimmedMeanFilterTab);
            comp.MaskSzeLabel_64.Text = 'd';
            comp.ATMF_d = uieditfield(comp.AlphatrimmedMeanFilterTab, 'numeric');
            comp.ATMF_d.Limits = [0 Inf];
            comp.ATMF_d.Value = 2;
            comp.NoiseDropDownLabel = uilabel(comp.RestorationTab);
            comp.NoiseDropDownLabel.Text = 'Noise';
            comp.MaskSzeLabel_31 = uilabel(comp.RestorationTab);
            comp.MaskSzeLabel_31.Text = 'Mask Size (m) ';
            comp.MaskSzeLabel_32 = uilabel(comp.RestorationTab);
            comp.MaskSzeLabel_32.Text = 'Mask Size (n) ';
            comp.Noise_mask_size_n = uieditfield(comp.RestorationTab, 'numeric');
            comp.Noise_mask_size_n.Limits = [0 Inf];
            comp.Noise_mask_size_n.Value = 3;
            comp.Noise_mask_size_m = uieditfield(comp.RestorationTab, 'numeric');
            comp.Noise_mask_size_m.Limits = [0 Inf];
            comp.Noise_mask_size_m.Value = 3;
            comp.MaskSzeLabel_24 = uilabel(comp.RestorationTab);
            comp.MaskSzeLabel_24.Text = 'Density';
            comp.NoiseDensity = uieditfield(comp.RestorationTab, 'numeric');
            comp.NoiseDensity.Limits = [0 1];
            comp.NoiseDensity.Value = 0.05;
            comp.NoiseDropDown = uidropdown(comp.RestorationTab);
            comp.NoiseDropDown.Items = {'Salt & Pepper', 'Gaussian'};
            comp.NoiseDropDown.Value = 'Salt & Pepper';
            comp.NotchFilterTab = uitab(comp.OperationTabGroup);
            comp.NotchFilterTab.Title = 'Notch Filter';
            comp.ImageDropDown_2Label = uilabel(comp.NotchFilterTab);
            comp.ImageDropDown_2Label.Text = 'Image';
            comp.Notch_ImageDropDown = uidropdown(comp.NotchFilterTab);
            comp.Notch_ImageDropDown.Items = {'Image 1', 'Image 2', 'Image 3', 'Image 4', 'Image 5', 'Image 6'};
            comp.Notch_ImageDropDown.Value = 'Image 1';
            comp.MaskSzeLabel_66 = uilabel(comp.NotchFilterTab);
            comp.MaskSzeLabel_66.Text = 'Density';
            comp.ATMF_density_2 = uieditfield(comp.NotchFilterTab, 'numeric');
            comp.ATMF_density_2.Limits = [0 Inf];
            comp.ATMF_density_2.Value = 106;
            comp.MotionDeblurringTab = uitab(comp.OperationTabGroup);
            comp.MotionDeblurringTab.Title = 'Motion Debluring';
            comp.DeblurringDistortedImage = uiaxes(comp.MotionDeblurringTab);
            title(comp.DeblurringDistortedImage, 'Distorted image')
            comp.DeblurringDistortedImage.XTick = [];
            comp.DeblurringDistortedImage.YTick = [];
            comp.NSRSliderLabel = uilabel(comp.MotionDeblurringTab);
            comp.NSRSliderLabel.Text = 'NSR';
            comp.DeblurringTabGroup = uitabgroup(comp.MotionDeblurringTab);
            comp.DeblurringMotionBlurTab = uitab(comp.DeblurringTabGroup);
            comp.DeblurringMotionBlurTab.Title = 'Motion Blur';
            comp.MaskSzeLabel_68 = uilabel(comp.DeblurringMotionBlurTab);
            comp.MaskSzeLabel_68.Text = 'Distance';
            comp.MotionBlur_angle = uieditfield(comp.DeblurringMotionBlurTab, 'numeric');
            comp.MotionBlur_angle.Limits = [0 360];
            comp.MotionBlur_angle.Value = 45;
            comp.MotionBlur_distance = uieditfield(comp.DeblurringMotionBlurTab, 'numeric');
            comp.MotionBlur_distance.Limits = [1 Inf];
            comp.MotionBlur_distance.RoundFractionalValues = 'on';
            comp.MotionBlur_distance.Value = 5;
            comp.MaskSzeLabel_69 = uilabel(comp.DeblurringMotionBlurTab);
            comp.MaskSzeLabel_69.Text = 'Angle';
            comp.DeblurringGaussianTab = uitab(comp.DeblurringTabGroup);
            comp.DeblurringGaussianTab.Title = 'Gaussian';
            comp.MaskSzeLabel_70 = uilabel(comp.DeblurringGaussianTab);
            comp.MaskSzeLabel_70.Text = 'Size';
            comp.GaussianBlur_sigma = uieditfield(comp.DeblurringGaussianTab, 'numeric');
            comp.GaussianBlur_sigma.Limits = [0 Inf];
            comp.GaussianBlur_sigma.Value = 3;
            comp.GaussianBlur_size = uieditfield(comp.DeblurringGaussianTab, 'numeric');
            comp.GaussianBlur_size.Limits = [1 Inf];
            comp.GaussianBlur_size.RoundFractionalValues = 'on';
            comp.GaussianBlur_size.Value = 5;
            comp.MaskSzeLabel_71 = uilabel(comp.DeblurringGaussianTab);
            comp.MaskSzeLabel_71.Text = 'Variance';
            comp.DeblurringApplyBlur = uicheckbox(comp.MotionDeblurringTab);
            comp.DeblurringApplyBlur.Text = 'Apply blur filter to input image';
            comp.NSRSlider = uislider(comp.MotionDeblurringTab);
            comp.NSRSlider.Limits = [1e-06 1];
            comp.NSRSlider.Value = 1e-06;
            comp.ImageDisplayPanel = uipanel(comp);
            comp.ImageDisplayPanel.Title = 'Image display';
            comp.OutputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.OutputImage, 'Output image')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.InputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.InputImage, 'Input image')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.ApplyButton = uibutton(comp, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Enable = 'off';
            comp.ApplyButton.Text = 'Apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end